classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImageFilteringPanel          matlab.ui.container.Panel
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        WindowSizeEditField          matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel     matlab.ui.control.Label
        KernelTypeDropDown           matlab.ui.control.DropDown
        KernelTypeDropDownLabel      matlab.ui.control.Label
        EnhanceButton_4              matlab.ui.control.Button
        HistogramEqualizationPanel   matlab.ui.container.Panel
        EnhanceButton_2              matlab.ui.control.Button
        HistogramMatchingPanel       matlab.ui.container.Panel
        ReferenceImageSelectorLabel  matlab.ui.control.Label
        LoadImageButton              matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        EnhanceButton_3              matlab.ui.control.Button
        IntensityTransformPanel      matlab.ui.container.Panel
        ContrastRangeSlider          matlab.ui.control.RangeSlider
        ContrastRangeSliderLabel     matlab.ui.control.Label
        EnhanceButton                matlab.ui.control.Button
        cconstantEditField           matlab.ui.control.NumericEditField
        cconstantEditFieldLabel      matlab.ui.control.Label
        GammaEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel          matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        ImageSelectorPanel           matlab.ui.container.Panel
        MainImagePreview             matlab.ui.control.Image
        MainImageLoadButton          matlab.ui.control.Button
        AnalyzeButton                matlab.ui.control.Button
        ImageEnhancerv10Label        matlab.ui.control.Label
        Logo                         matlab.ui.control.Image
        EnhancementStrategyDropDown  matlab.ui.control.DropDown
        EnhancementStrategyDropDownLabel  matlab.ui.control.Label
        StatsTable                   matlab.ui.control.Table
        TabGroup                     matlab.ui.container.TabGroup
        RGBTab                       matlab.ui.container.Tab
        RGBAxes                      matlab.ui.control.UIAxes
        RedTab                       matlab.ui.container.Tab
        RAxes                        matlab.ui.control.UIAxes
        GreenTab                     matlab.ui.container.Tab
        GAxes                        matlab.ui.control.UIAxes
        BlueTab                      matlab.ui.container.Tab
        BAxes                        matlab.ui.control.UIAxes
        ValueTab                     matlab.ui.container.Tab
        ValueAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainImage % image
        CurrentImage % result of the enhancements applied so far (starts as MainImage)
        Steps = {} % names of those enhancements, in order
        ResultFigure % pop-up that shows CurrentImage
    end

    methods (Access = private)

        % Apply an enhancement to the current image, so enhancements chain
        function applyStep(app, preset, request, stepName)
            request.Image = app.CurrentImage;
            result = preset.apply(request);
            app.CurrentImage = result.Image;
            app.Steps{end + 1} = stepName;
            showResult(app);
        end

        % Show CurrentImage and its histograms in the result pop-up
        function showResult(app)
            img = app.CurrentImage;
            if ~isempty(app.ResultFigure) && isvalid(app.ResultFigure)
                delete(app.ResultFigure);
            end
            fig = uifigure('Name', 'Enhancement Result', 'Position', [200 200 700 500]);
            app.ResultFigure = fig;
            tg = uitabgroup(fig, 'Position', [0 40 700 460]);
            tabImg   = uitab(tg, 'Title', 'Image');
            tabRGB   = uitab(tg, 'Title', 'RGB Histogram');
            tabValue = uitab(tg, 'Title', 'Value Histogram');

            uibutton(fig, 'push', 'Text', 'Save Image', 'Position', [580 10 110 22], ...
                'ButtonPushedFcn', @(~, ~) saveResult(app));
            uibutton(fig, 'push', 'Text', 'Start Over', 'Position', [460 10 110 22], ...
                'ButtonPushedFcn', @(~, ~) startOver(app));

            % Tab 1: result image
            axImg = uiaxes(tabImg, 'Position', [10 10 670 400]);
            imshow(img, 'Parent', axImg);
            title(axImg, ['Result: ' strjoin(app.Steps, ' > ')]);

            % Histogram data
            h = Histogram.forImage(img);
            levels = 0:255;

            % Tab 2: combined RGB histogram (empty for a grayscale image)
            axRGB = uiaxes(tabRGB, 'Position', [10 10 670 400]);
            if isfield(h, 'red')
                hold(axRGB, 'on');
                area(axRGB, levels, h.red,   'FaceColor', 'r', 'FaceAlpha', 0.4, 'EdgeColor', 'r', 'LineWidth', 0.5, 'DisplayName', 'Red');
                area(axRGB, levels, h.green, 'FaceColor', 'g', 'FaceAlpha', 0.4, 'EdgeColor', 'g', 'LineWidth', 0.5, 'DisplayName', 'Green');
                area(axRGB, levels, h.blue,  'FaceColor', 'b', 'FaceAlpha', 0.4, 'EdgeColor', 'b', 'LineWidth', 0.5, 'DisplayName', 'Blue');
                hold(axRGB, 'off');
                legend(axRGB);
            end
            xlim(axRGB, [0 255]);
            title(axRGB, 'RGB Histogram of Result');
            xlabel(axRGB, 'Intensity');
            ylabel(axRGB, 'Pixel Count');

            % Tab 3: value histogram
            axVal = uiaxes(tabValue, 'Position', [10 10 670 400]);
            area(axVal, levels, h.value, 'FaceColor', [0.3 0.3 0.3], 'FaceAlpha', 0.5, 'EdgeColor', 'k', 'LineWidth', 1);
            xlim(axVal, [0 255]);
            title(axVal, 'Value Histogram of Result');
            xlabel(axVal, 'Intensity');
            ylabel(axVal, 'Pixel Count');
        end

        % Save CurrentImage at full resolution (not a rendering of the axes)
        function saveResult(app)
            [file, folder] = uiputfile({'*.png'; '*.jpg'; '*.bmp'; '*.tif'}, 'Save Enhanced Image', 'enhanced.png');
            if isequal(file, 0)
                return
            end
            imwrite(app.CurrentImage, fullfile(folder, file));
        end

        % Drop the applied enhancements and go back to the loaded image
        function startOver(app)
            app.CurrentImage = app.MainImage;
            app.Steps = {};
            if ~isempty(app.ResultFigure) && isvalid(app.ResultFigure)
                delete(app.ResultFigure);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDir = fileparts(mfilename('fullpath'));
            addpath(fullfile(appDir, '..', 'utils'));
            addpath(fullfile(appDir, '..', 'enhancements'));
            addpath(fullfile(appDir, '..', 'enhancements', 'strategies'));
        end

        % Button pushed function: MainImageLoadButton
        function MainImageLoadButtonPushed(app, event)
            [file, folder] = uigetfile('*.png;*.jpg;*.jpeg');
            if isequal(file, 0) 
                return
            end
            path = fullfile(folder, file);
            app.MainImagePreview.ImageSource = path;
            app.AnalyzeButton.Enable = "on";
        end

        % Callback function
        function MainImageResetButtonPushed(app, event)
            app.MainImagePreview.ImageSource = '';
            app.AnalyzeButton.Enable = "off";
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if isempty(app.MainImagePreview.ImageSource)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image Loaded');
                return
            end
            app.MainImage = imread(app.MainImagePreview.ImageSource);
            startOver(app);

            % 1. Render graphs
            h = Histogram.forImage(app.MainImage);
            levels = 0:255;

            cla(app.RGBAxes);
            legend(app.RGBAxes, 'off');
            cla(app.RAxes);
            cla(app.GAxes);
            cla(app.BAxes);

            % A grayscale image has only the Value histogram: leave the R/G/B tabs empty
            if ~isfield(h, 'red')
                app.TabGroup.SelectedTab = app.ValueTab;
            else
                % Combined RGB histogram
                hold(app.RGBAxes, 'on');
                area(app.RGBAxes, levels, h.red,   'FaceColor', 'r', 'FaceAlpha', 0.4, 'EdgeColor', 'r', 'LineWidth', 0.5, 'DisplayName', 'Red');
                area(app.RGBAxes, levels, h.green, 'FaceColor', 'g', 'FaceAlpha', 0.4, 'EdgeColor', 'g', 'LineWidth', 0.5, 'DisplayName', 'Green');
                area(app.RGBAxes, levels, h.blue,  'FaceColor', 'b', 'FaceAlpha', 0.4, 'EdgeColor', 'b', 'LineWidth', 0.5, 'DisplayName', 'Blue');
                hold(app.RGBAxes, 'off');
                legend(app.RGBAxes);
                xlim(app.RGBAxes, [0 255]);

                % Red channel
                area(app.RAxes, levels, h.red, 'FaceColor', 'r', 'FaceAlpha', 0.5, 'EdgeColor', 'r', 'LineWidth', 1);
                xlim(app.RAxes, [0 255]);

                % Green channel
                area(app.GAxes, levels, h.green, 'FaceColor', 'g', 'FaceAlpha', 0.5, 'EdgeColor', 'g', 'LineWidth', 1);
                xlim(app.GAxes, [0 255]);

                % Blue channel
                area(app.BAxes, levels, h.blue, 'FaceColor', 'b', 'FaceAlpha', 0.5, 'EdgeColor', 'b', 'LineWidth', 1);
                xlim(app.BAxes, [0 255]);
            end

            % Value channel
            cla(app.ValueAxes);
            area(app.ValueAxes, levels, h.value, 'FaceColor', [0.3 0.3 0.3], 'FaceAlpha', 0.5, 'EdgeColor', 'k', 'LineWidth', 1);
            xlim(app.ValueAxes, [0 255]);

            % 2. Render stats
            stats = imageStats(app.MainImage);

            % Fill statistics table
            app.StatsTable.Data = stats;
            app.StatsTable.ColumnName = ...
                {'Max', 'Min', 'Mean', 'Std', 'Entropy'};

            % Set row names based on image type
            if size(app.MainImage, 3) == 1
                app.StatsTable.RowName = {'Gray'};
            else
                app.StatsTable.RowName = {'Red'; 'Green'; 'Blue'};
            end
        end

        % Value changed function: EnhancementStrategyDropDown
        function EnhancementStrategyDropDownValueChanged(app, event)
            value = app.EnhancementStrategyDropDown.Value;
            % Hide all panels
            app.IntensityTransformPanel.Visible = 'off';
            app.HistogramEqualizationPanel.Visible = 'off';
            app.HistogramMatchingPanel.Visible = 'off';
            app.ImageFilteringPanel.Visible = 'off';

            % Show the selected one
            switch value
                case 'Histogram Equalization'
                    app.HistogramEqualizationPanel.Visible = 'on';
                case 'Intensity Transform'
                    app.IntensityTransformPanel.Visible = 'on';
                case 'Histogram Matching'
                    app.HistogramMatchingPanel.Visible = 'on';
                case 'Image Filtering'
                    app.ImageFilteringPanel.Visible = 'on';
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            
            switch value
                case 'Negative'
                    app.GammaEditFieldLabel.Enable = "off";
                    app.GammaEditField.Enable = "off";
                    app.cconstantEditFieldLabel.Enable = "off";
                    app.cconstantEditField.Enable = "off";
                    app.ContrastRangeSliderLabel.Enable = "off";
                    app.ContrastRangeSlider.Enable = "off";
                case 'Log Transform'
                    app.GammaEditFieldLabel.Enable = "off";
                    app.GammaEditField.Enable = "off";
                    app.cconstantEditFieldLabel.Enable = "on";
                    app.cconstantEditField.Enable = "on";
                    app.ContrastRangeSliderLabel.Enable = "off";
                    app.ContrastRangeSlider.Enable = "off";
                case 'Power-Law (Gamma)'
                    app.GammaEditFieldLabel.Enable = "on";
                    app.GammaEditField.Enable = "on";
                    app.cconstantEditFieldLabel.Enable = "off";
                    app.cconstantEditField.Enable = "off";
                    app.ContrastRangeSliderLabel.Enable = "off";
                    app.ContrastRangeSlider.Enable = "off";
                case 'Contrast Stretching'
                    app.GammaEditField.Enable = "off";
                    app.GammaEditFieldLabel.Enable = "off";
                    app.cconstantEditField.Enable = "off";
                    app.cconstantEditFieldLabel.Enable = "off";
                    app.ContrastRangeSliderLabel.Enable = "on";
                    app.ContrastRangeSlider.Enable = "on";
            end
        end

        % Button pushed function: EnhanceButton
        function EnhanceButtonPushed(app, event)
            if isempty(app.MainImage)
                uialert(app.UIFigure, 'Please load/analyze the image first.', 'No Image');
                return;
            end

            method = app.MethodDropDown.Value;
            request = EnhancementRequest;

            switch method
                case 'Negative'
                    preset = IntensityTransformation('negative');
                    stepName = method;
                case 'Log Transform'
                    preset = IntensityTransformation('log');
                    preset.C = app.cconstantEditField.Value;
                    stepName = sprintf('%s c=%g', method, preset.C);
                case 'Power-Law (Gamma)'
                    preset = IntensityTransformation('gamma');
                    preset.Gamma = app.GammaEditField.Value;
                    stepName = sprintf('Gamma %g', preset.Gamma);
                case 'Contrast Stretching'
                    preset = IntensityTransformation('stretch');
                    preset.InputRange = app.ContrastRangeSlider.Value;
                    stepName = sprintf('Stretch [%g %g]', round(preset.InputRange));
            end
            applyStep(app, preset, request, stepName);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, folder] = uigetfile('*.png;*.jpg;*.jpeg');
            if isequal(file, 0) 
                return
            end
            path = fullfile(folder, file);
            app.Image2.ImageSource = path;
            app.EnhanceButton_3.Enable = "on";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image2.ImageSource = '';
            app.EnhanceButton_3.Enable = "off";
        end

        % Button pushed function: EnhanceButton_3
        function EnhanceButton_3Pushed(app, event)
            if isempty(app.MainImage)
                uialert(app.UIFigure, 'Please load/analyze the image first.', 'No Image');
                return;
            end

            request = EnhancementRequest;
            request.ReferenceImage = imread(app.Image2.ImageSource);
            preset = HistogramMatching();
            applyStep(app, preset, request, 'Histogram Matching');
        end

        % Button pushed function: EnhanceButton_2
        function EnhanceButton_2Pushed(app, event)
            if isempty(app.MainImage)
                uialert(app.UIFigure, 'Please load/analyze the image first.', 'No Image');
                return;
            end

            request = EnhancementRequest;
            preset = HistogramEqualization();
            applyStep(app, preset, request, 'Histogram Equalization');
        end

        % Button pushed function: EnhanceButton_4
        function EnhanceButton_4Pushed(app, event)
            if isempty(app.MainImage)
                uialert(app.UIFigure, 'Please load/analyze the image first.', 'No Image');
                return;
            end

            request = EnhancementRequest;

            kernelType = app.KernelTypeDropDown.Value;
            windowSize = app.WindowSizeEditField.Value;
            sigma      = app.SigmaEditField.Value;

            % Window size must be a positive odd integer (Laplacian ignores it)
            if ~strcmp(kernelType, 'Laplacian')
                if windowSize < 1 || mod(windowSize, 2) == 0 || windowSize ~= round(windowSize)
                    uialert(app.UIFigure, 'Window size must be a positive odd integer.', 'Invalid Window Size');
                    return;
                end
            end

            % Sigma only matters for Gaussian
            if strcmp(kernelType, 'Gaussian') && sigma <= 0
                uialert(app.UIFigure, 'Sigma must be greater than 0.', 'Invalid Sigma');
                return;
            end
            
            if strcmp(kernelType, 'Median')
                preset = SpatialFilter('median', windowSize);
            else
                kernel = SpatialFilter.makeKernel(kernelType, windowSize, sigma);
                preset = SpatialFilter('linear', kernel);
            end

            switch kernelType
                case 'Laplacian'
                    stepName = kernelType;
                case 'Gaussian'
                    stepName = sprintf('Gaussian %d sigma=%g', windowSize, sigma);
                otherwise
                    stepName = sprintf('%s %d', kernelType, windowSize);
            end
            applyStep(app, preset, request, stepName);
        end

        % Value changed function: KernelTypeDropDown
        function KernelTypeDropDownValueChanged(app, event)
            value = app.KernelTypeDropDown.Value;
            switch value
                case {'Mean', 'Box', 'Median'}
                    app.WindowSizeEditFieldLabel.Enable = 'on';
                    app.WindowSizeEditField.Enable = 'on';
                    app.SigmaEditFieldLabel.Enable = 'off';
                    app.SigmaEditField.Enable      = 'off';
                case 'Gaussian'
                    app.WindowSizeEditFieldLabel.Enable = 'on';
                    app.WindowSizeEditField.Enable = 'on';
                    app.SigmaEditFieldLabel.Enable      = 'on';
                    app.SigmaEditField.Enable      = 'on';
                case 'Laplacian'
                    app.WindowSizeEditFieldLabel.Enable = 'off';
                    app.WindowSizeEditField.Enable = 'off';
                    app.SigmaEditFieldLabel.Enable      = 'off';
                    app.SigmaEditField.Enable      = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RGBTab = uitab(app.TabGroup);
            app.RGBTab.Title = 'RGB';
            app.RGBAxes = uiaxes(app.RGBTab);
            title(app.RGBAxes, 'RGB Histogram')
            xlabel(app.RGBAxes, 'Intensity')
            ylabel(app.RGBAxes, 'Freq')
            app.RGBAxes.XTick = [0 64 128 192 255];
            app.RGBAxes.XTickLabel = {'0'; '64'; '128'; '192'; '255'};
            app.RGBAxes.YTick = [];
            app.RedTab = uitab(app.TabGroup);
            app.RedTab.Title = 'Red';
            app.RAxes = uiaxes(app.RedTab);
            title(app.RAxes, 'Red Channel Histogram')
            xlabel(app.RAxes, 'Intensity')
            ylabel(app.RAxes, 'Freq')
            app.RAxes.XTick = [0 64 128 192 255];
            app.RAxes.XTickLabel = {'0'; '64'; '128'; '192'; '255'};
            app.RAxes.YTick = [];
            app.GreenTab = uitab(app.TabGroup);
            app.GreenTab.Title = 'Green';
            app.GAxes = uiaxes(app.GreenTab);
            title(app.GAxes, 'Green Channel Histogram')
            xlabel(app.GAxes, 'Intensity')
            ylabel(app.GAxes, 'Freq')
            app.GAxes.XTick = [0 64 128 192 255];
            app.GAxes.XTickLabel = {'0'; '64'; '128'; '192'; '255'};
            app.GAxes.YTick = [];
            app.BlueTab = uitab(app.TabGroup);
            app.BlueTab.Title = 'Blue';
            app.BAxes = uiaxes(app.BlueTab);
            title(app.BAxes, 'Blue Channel Histogram')
            xlabel(app.BAxes, 'Intensity')
            ylabel(app.BAxes, 'Freq')
            app.BAxes.XTick = [0 64 128 192 255];
            app.BAxes.XTickLabel = {'0'; '64'; '128'; '192'; '255'};
            app.BAxes.YTick = [];
            app.ValueTab = uitab(app.TabGroup);
            app.ValueTab.Title = 'Value';
            app.ValueAxes = uiaxes(app.ValueTab);
            title(app.ValueAxes, 'Value Histogram')
            xlabel(app.ValueAxes, 'Intensity')
            ylabel(app.ValueAxes, 'Freq')
            app.ValueAxes.XLimMode = 'manual';
            app.ValueAxes.XTick = [0 64 128 192 255];
            app.ValueAxes.XTickLabel = {'0'; '64'; '128'; '192'; '255'};
            app.ValueAxes.YTick = [];
            app.StatsTable = uitable(app.UIFigure);
            app.StatsTable.ColumnName = {'Max'; 'Min'; 'Mean'; 'Std'; 'Entropy'};
            app.StatsTable.RowName = {};
            app.EnhancementStrategyDropDownLabel = uilabel(app.UIFigure);
            app.EnhancementStrategyDropDownLabel.Text = 'Enhancement Strategy';
            app.EnhancementStrategyDropDown = uidropdown(app.UIFigure);
            app.EnhancementStrategyDropDown.Items = {'Intensity Transform', 'Histogram Equalization', 'Histogram Matching', 'Image Filtering'};
            app.EnhancementStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @EnhancementStrategyDropDownValueChanged, true);
            app.EnhancementStrategyDropDown.Placeholder = 'Select a strategy...';
            app.EnhancementStrategyDropDown.Value = 'Intensity Transform';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'resources', 'nen-chart.jpg');
            app.ImageEnhancerv10Label = uilabel(app.UIFigure);
            app.ImageEnhancerv10Label.Text = 'Image Enhancer v1.0 ';
            app.ImageSelectorPanel = uipanel(app.UIFigure);
            app.ImageSelectorPanel.Title = 'Image Selector';
            app.AnalyzeButton = uibutton(app.ImageSelectorPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.MainImageLoadButton = uibutton(app.ImageSelectorPanel, 'push');
            app.MainImageLoadButton.ButtonPushedFcn = createCallbackFcn(app, @MainImageLoadButtonPushed, true);
            app.MainImageLoadButton.Text = 'Load Image';
            app.MainImagePreview = uiimage(app.ImageSelectorPanel);
            app.IntensityTransformPanel = uipanel(app.UIFigure);
            app.IntensityTransformPanel.Title = 'Intensity Transform';
            app.MethodDropDownLabel = uilabel(app.IntensityTransformPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.IntensityTransformPanel);
            app.MethodDropDown.Items = {'Negative', 'Log Transform', 'Power-Law (Gamma)', 'Contrast Stretching'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Negative';
            app.GammaEditFieldLabel = uilabel(app.IntensityTransformPanel);
            app.GammaEditFieldLabel.Enable = 'off';
            app.GammaEditFieldLabel.Text = 'Gamma (γ)';
            app.GammaEditField = uieditfield(app.IntensityTransformPanel, 'numeric');
            app.GammaEditField.Enable = 'off';
            app.cconstantEditFieldLabel = uilabel(app.IntensityTransformPanel);
            app.cconstantEditFieldLabel.Enable = 'off';
            app.cconstantEditFieldLabel.Text = 'c constant';
            app.cconstantEditField = uieditfield(app.IntensityTransformPanel, 'numeric');
            app.cconstantEditField.Enable = 'off';
            app.EnhanceButton = uibutton(app.IntensityTransformPanel, 'push');
            app.EnhanceButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButtonPushed, true);
            app.EnhanceButton.Text = 'Enhance';
            app.ContrastRangeSliderLabel = uilabel(app.IntensityTransformPanel);
            app.ContrastRangeSliderLabel.Enable = 'off';
            app.ContrastRangeSliderLabel.Text = 'Contrast Range';
            app.ContrastRangeSlider = uislider(app.IntensityTransformPanel, 'range');
            app.ContrastRangeSlider.Limits = [0 255];
            app.ContrastRangeSlider.MajorTicks = [0 64 128 192 255];
            app.ContrastRangeSlider.MajorTickLabels = {'0', '64', '128', '192', '255'};
            app.ContrastRangeSlider.MinorTicksMode = 'manual';
            app.ContrastRangeSlider.Enable = 'off';
            app.ContrastRangeSlider.Value = [0 255];
            app.HistogramMatchingPanel = uipanel(app.UIFigure);
            app.HistogramMatchingPanel.Title = 'Histogram Matching';
            app.HistogramMatchingPanel.Visible = 'off';
            app.EnhanceButton_3 = uibutton(app.HistogramMatchingPanel, 'push');
            app.EnhanceButton_3.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButton_3Pushed, true);
            app.EnhanceButton_3.Text = 'Enhance';
            app.Image2 = uiimage(app.HistogramMatchingPanel);
            app.ResetButton = uibutton(app.HistogramMatchingPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadImageButton = uibutton(app.HistogramMatchingPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ReferenceImageSelectorLabel = uilabel(app.HistogramMatchingPanel);
            app.ReferenceImageSelectorLabel.Text = 'Reference Image Selector';
            app.HistogramEqualizationPanel = uipanel(app.UIFigure);
            app.HistogramEqualizationPanel.Title = 'Histogram Equalization';
            app.HistogramEqualizationPanel.Visible = 'off';
            app.EnhanceButton_2 = uibutton(app.HistogramEqualizationPanel, 'push');
            app.EnhanceButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButton_2Pushed, true);
            app.EnhanceButton_2.Text = 'Enhance';
            app.ImageFilteringPanel = uipanel(app.UIFigure);
            app.ImageFilteringPanel.Title = 'Image Filtering';
            app.ImageFilteringPanel.Visible = 'off';
            app.EnhanceButton_4 = uibutton(app.ImageFilteringPanel, 'push');
            app.EnhanceButton_4.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButton_4Pushed, true);
            app.EnhanceButton_4.Text = 'Enhance';
            app.KernelTypeDropDownLabel = uilabel(app.ImageFilteringPanel);
            app.KernelTypeDropDownLabel.Text = 'Kernel Type';
            app.KernelTypeDropDown = uidropdown(app.ImageFilteringPanel);
            app.KernelTypeDropDown.Items = {'Mean', 'Box', 'Gaussian', 'Laplacian', 'Median'};
            app.KernelTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelTypeDropDownValueChanged, true);
            app.KernelTypeDropDown.Value = 'Mean';
            app.WindowSizeEditFieldLabel = uilabel(app.ImageFilteringPanel);
            app.WindowSizeEditFieldLabel.Text = 'Kernel/Window Size';
            app.WindowSizeEditField = uieditfield(app.ImageFilteringPanel, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.ImageFilteringPanel);
            app.SigmaEditFieldLabel.Enable = 'off';
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.ImageFilteringPanel, 'numeric');
            app.SigmaEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end